classdef ipapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OpenMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        ExitMenu                 matlab.ui.container.Menu
        ConversionMenu           matlab.ui.container.Menu
        rgb2grayMenu             matlab.ui.container.Menu
        resizeMenu               matlab.ui.container.Menu
        split2rgbMenu            matlab.ui.container.Menu
        rotateMenu               matlab.ui.container.Menu
        FourierMenu              matlab.ui.container.Menu
        LowpassMenu              matlab.ui.container.Menu
        HighpassMenu             matlab.ui.container.Menu
        ButterworthlowpassMenu   matlab.ui.container.Menu
        ButterworthhighpassMenu  matlab.ui.container.Menu
        GaussianlowpassMenu      matlab.ui.container.Menu
        GaussianhighpassMenu     matlab.ui.container.Menu
        ThresholdingMenu         matlab.ui.container.Menu
        OtsuMenu                 matlab.ui.container.Menu
        UserMenu                 matlab.ui.container.Menu
        EdgeMenu                 matlab.ui.container.Menu
        SobelMenu                matlab.ui.container.Menu
        PrewittMenu              matlab.ui.container.Menu
        RobertsMenu              matlab.ui.container.Menu
        logMenu                  matlab.ui.container.Menu
        zerocrossMenu            matlab.ui.container.Menu
        CannyMenu                matlab.ui.container.Menu
        approxcannyMenu          matlab.ui.container.Menu
        HoughMenu                matlab.ui.container.Menu
        SnakeMenu                matlab.ui.container.Menu
        DeepLearningMenu         matlab.ui.container.Menu
        ColorGoogLeNetMenu       matlab.ui.container.Menu
        GrayScaleGooLeNetMenu    matlab.ui.container.Menu
        ThresholdingSliderLabel  matlab.ui.control.Label
        ThresholdingSlider       matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [app.Data.FileName, app.Data.PathName] = uigetfile( ...
                {  '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
                'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';...
                '*.bmp','WindowsBitmap (*.bmp)';...
                '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
                '*.pbm','PortableBitmap (*.pbm)';...
                '*.pgm','PortableGraymap(*.pgm)';...
                '*.png','PortableNetwork Graphics (*.png)';...
                '*.ppm','PortablePixmap(*.ppm)';...
                '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
                '*.*', 'All Files (*.*)'},...
                'Select an Image File to Open');
            figure(app.UIFigure);
            
            if(app.Data.FileName)==0
                return
            else
                %read in image
                app.Data.img= imread([app.Data.PathName, app.Data.FileName]);
                app.Data.result= app.Data.img;
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes);
                %get image information
                [app.Data.rows,  app.Data.cols,  app.Data.dim] = size(app.Data.img);
                info = imfinfo([app.Data.PathName,  app.Data.FileName]);
                app.Data.bitdepth= info.BitDepth;
                app.Data.ColorType= info.ColorType;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile( ...
                {  '*.bmp','WindowsBitmap (*.bmp)'; ...
                '*.jpg','JPEG(*.jpg)'; ...
                '*.pbm','PortableBitmap (*.pbm)'; ...
                '*.pgm','PortableGraymap(*.pgm)'; ...
                '*.png','PortableNetwork Graphics (*.png)'; ...
                '*.ppm','PortablePixmap(*.ppm)'; ...
                '*.tif','TaggedImage File Format (*.tif)'}, ...
                'Save Image as',...
                'untitled');
            figure(app.UIFigure);
            if(filename)==0
                return
            else
                fmt(1,:)='bmp';
                fmt(2,:)='jpg';
                fmt(3,:)='pbm';
                fmt(4,:)='pgm';
                fmt(5,:)='png';
                fmt(6,:)='ppm';
                fmt(7,:)='tif';
                %write out image
                imwrite(app.Data.result,  [pathname, filename], fmt(filterindex,:));
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
            
        end

        % Menu selected function: rgb2grayMenu
        function rgb2grayMenuSelected(app, event)
            if(app.Data.dim)==3
                %convert RGB to gray image
                app.Data.result= rgb2gray(app.Data.img);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('This image is not a color image.','Warning')
                return
            end
        end

        % Menu selected function: resizeMenu
        function resizeMenuSelected(app, event)
            %Create and open input dialog box
            prompt={'Image Height:',...
                'Image Width:',...
                'Interpolation Method (1=nearest, 2=bilinear, 3=bicubic):'};
            name='Image Resizing';
            numlines=1;
            defaultanswer={num2str(app.Data.rows), num2str(app.Data.cols),'1'}
            answer = inputdlg(prompt, name, numlines,  defaultanswer);
            if isempty(answer)
                return
            else
                switch answer{3}
                    case {'1'}
                        meth_str= 'nearest';
                    case {'2'}
                        meth_str= 'bilinear';
                    case {'3'}
                        meth_str= 'bicubic';
                end
                %Resize image
                app.Data.result= imresize(app.Data.img, [str2num(answer{1}) str2num(answer{2})], meth_str);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
        end

        % Menu selected function: split2rgbMenu
        function split2rgbMenuSelected(app, event)
            %show image
            for i=1:3
                SI = figure;
                figure(SI);
                iptsetpref('ImshowBorder','tight')  %removes figure border
                imshow(app.Data.img(:,:,i));
            end
        end

        % Menu selected function: rotateMenu
        function rotateMenuSelected(app, event)
            i = app.Data.img
            
            questions = {'rotateangle'};
            name='RotateAangle';
            numlines=1;
            angle1 = {'90'}
            size = inputdlg(questions,name,numlines,angle1)
%             pix_grp = str2num(size{1})
            if isempty(size)
                return
            end
            app.Data.result = imrotate(i,str2num(size{1}))
            imshow(app.Data.result , 'Parent',app.UIAxes2)
        end

        % Menu selected function: LowpassMenu
        function LowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2),2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            %create a small circle mask
            [x,y] = meshgrid(wr,hr);
            z = sqrt(x.^2+y.^2);
            c = (z < 15);
            
            %Fourier lowpassfilter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
        end

        % Menu selected function: HighpassMenu
        function HighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2),2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            %create a small circle mask
            [x,y] = meshgrid(wr,hr);
            z = sqrt(x.^2+y.^2);
            c = (z > 15);
            
            %Fourier lowpassfilter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
        end

        % Menu selected function: OtsuMenu
        function OtsuMenuSelected(app, event)
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %Global image threshold using Otsu's method
                level = graythresh(app.Data.img);
                app.Data.result= imbinarize(app.Data.img, level);
                level = round(level*255)
                [counts,x] = imhist(app.Data.img, 256);
                SI = figure;
                figure(SI);
                stem(x,counts,'.');
                hold on;
                xline(level,'--r');
                hold off;
            elseif (app.Data.dim==3)
                %warndlg('The image must be a grayscale image.','Warning')
                %return
                level = graythresh(app.Data.img);
                app.Data.result= imbinarize(app.Data.img(:,:,1), level) | imbinarize(app.Data.img(:,:,2), level) | imbinarize(app.Data.img(:,:,3), level);
                level = round(level*255)
                [counts,x] = imhist(app.Data.img, 256);
                SI = figure;
                figure(SI);
                stem(x,counts,'.');
                hold on;
                xline(level,'--r');
                hold off;
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Value changed function: ThresholdingSlider
        function ThresholdingSliderValueChanged(app, event)
            value = app.ThresholdingSlider.Value;
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %Global image threshold using slider's value
                app.Data.result= imbinarize(app.Data.img,  value/255)
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            elseif (app.Data.dim)==3
                app.Data.result= imbinarize(rgb2gray(app.Data.img),value/255);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
            
            %app.Data.result= imbinarize(rgb2gray(app.Data.img),value/255);
            %show image
%             imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: UserMenu
        function UserMenuSelected(app, event)
            if app.UserMenu.Checked==0
                set(app.UserMenu, 'Checked', 'on');
                set(app.ThresholdingSlider,'visible','on')
                set(app.ThresholdingSliderLabel,'visible','on')
            else
                set(app.UserMenu, 'Checked', 'off');
                set(app.ThresholdingSlider,  'visible',  'off')
                set(app.ThresholdingSliderLabel,  'visible',  'off')
            end
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'Sobel');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'Sobel')| edge(app.Data.img(:,:,2), 'Sobel') | edge(app.Data.img(:,:,3), 'Sobel');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'Prewitt');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'Prewitt')| edge(app.Data.img(:,:,2), 'Prewitt') | edge(app.Data.img(:,:,3), 'Prewitt');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: RobertsMenu
        function RobertsMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'Roberts');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'Roberts')| edge(app.Data.img(:,:,2), 'Roberts') | edge(app.Data.img(:,:,3), 'Roberts');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'log');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'log')| edge(app.Data.img(:,:,2), 'log') | edge(app.Data.img(:,:,3), 'log');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: zerocrossMenu
        function zerocrossMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'zerocross');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'zerocross')| edge(app.Data.img(:,:,2), 'zerocross') | edge(app.Data.img(:,:,3), 'zerocross');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: CannyMenu
        function CannyMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'Canny');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'Canny')| edge(app.Data.img(:,:,2), 'Canny') | edge(app.Data.img(:,:,3), 'Canny');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: approxcannyMenu
        function approxcannyMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'approxcanny');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.', 'Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'approxcanny')| edge(app.Data.img(:,:,2), 'approxcanny') | edge(app.Data.img(:,:,3), 'approxcanny');
            end
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HoughMenu
        function HoughMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'Canny');
            elseif (app.Data.dim==3)
                %                 warndlg('The image cannot be processed.','Warning')
                %                 return
                app.Data.result= edge(app.Data.img(:,:,1), 'Canny') | edge(app.Data.img(:,:,2), 'Canny')  | edge(app.Data.img(:,:,3), 'Canny');
            end
            %houghtransform
            [H,T,R] = hough(app.Data.result);
            P  = houghpeaks(H,3);%設定前3個最大數目(最明顯的直線)
            lines = houghlines(app.Data.result,T,R,P);
            %show image with lines
            app.Data.result= uint8(255*app.Data.result);
            for k = 1:length(lines)
                app.Data.result= insertShape(app.Data.result,'Line',...
                    [lines(k).point1,lines(k).point2],'LineWidth',5,'Color','r');
            end
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SnakeMenu
        function SnakeMenuSelected(app, event)
            if size(app.Data.img,3)==1 %grayscale image
                imshow(app.Data.img,  'Parent', app.UIAxes);
                SI = figure;
                figure(SI);
                imshow(app.Data.img,[]);
                %snake initialization
                
                cd snake
                [x,y] = snakeinit(1);
                [x,y] = snakeinterp(x,y,3,1);
                snakedisp(x,y,'r');
                % Compute its edge map
                [gx,gy] = gradient(double(app.Data.img));
                magG = sqrt(gx.*gx+gy.*gy);
                %compute GVF of edge map
                [u,v] = GVF(magG, 0.2, 80);
                mag= sqrt(u.*u+v.*v);
                px = u./(mag+1e-10); py = v./(mag+1e-10);
                % snake deformation
                for i=1:20
                    [x,y] = snakedeform(x,y,0.05,0,1,0.6,px,py,5);
                    [x,y] = snakeinterp(x,y,3,1);
                    if (rem(i,2) == 0)
                        snakedisp(x,y,'r');
                        pause(0.5);
                    end
                end
                cd ..
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2,[ x;x(1)],[y;y(1)],'r');
                hold(app.UIAxes2, 'off');
            else
                warndlg('The image cannot be processed.','Warning')
                return
            end
        end

        % Menu selected function: ColorGoogLeNetMenu
        function ColorGoogLeNetMenuSelected(app, event)
            if size(app.Data.img,3)==3 %color image
                % load the pretrainedGoogLeNetnetwork
                net = googlenet;
                % get the input size from layer 1
                inputSize = net.Layers(1).InputSize
                % getthe classification output names from the last layer
                classNames= net.Layers(end).ClassNames;
                % resize the image to the input size of the network
                I = imresize(app.Data.img,inputSize(1:2));
                [label, scores] = classify(net, I);
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                app.UIAxes2.Title.String = string(label)+", "+num2str(100*scores(classNames==label),3)+"%";
            else
                warndlg('The image must be a color image.','Warning')
                return
            end
        end

        % Menu selected function: ButterworthlowpassMenu
        function ButterworthlowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2),2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            D = 15;
            n = 1;
            %create a Butterworth lowpassmask
            [x,y] = meshgrid(wr,hr);
            c = 1./(1+ ( (x.^2+y.^2) /D ).^(2*n) );
            
            
            %Fourier lowpassfilter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ButterworthhighpassMenu
        function ButterworthhighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2),2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            D = 15;
            n = 1;
            %create a Butterworth highpassmask
            [x,y] = meshgrid(wr,hr);
            c = 1-1./(1+ ( (x.^2+y.^2) /D ).^(2*n) );
            
            %Fourier lowpassfilter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GaussianlowpassMenu
        function GaussianlowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2),2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            hsize= size(app.Data.img, 1:2);
            sigma = 10;
            %create a Gaussian lowpassmask
            [x,y] = meshgrid(wr,hr);
            c = mat2gray(fspecial('gaussian', hsize, sigma));
            
            %Fourier lowpassfilter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GaussianhighpassMenu
        function GaussianhighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2),2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            hsize= size(app.Data.img, 1:2);
            sigma = 10;
            %create a Gaussian highpassmask
            [x,y] = meshgrid(wr,hr);
            c = 1 - mat2gray(fspecial('gaussian', hsize, sigma));
            
            %Fourier lowpassfilter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GrayScaleGooLeNetMenu
        function GrayScaleGooLeNetMenuSelected(app, event)
            img=app.Data.img;
            for i=1:3
                app.Data.img(:,:,i)=img;
            end
            if size(app.Data.img,3)==3 %GRAY image
                % load the pretrainedGoogLeNetnetwork
                net = googlenet;
                % get the input size from layer 1
                inputSize = net.Layers(1).InputSize
                % getthe classification output names from the last layer
                classNames= net.Layers(end).ClassNames;
                % resize the image to the input size of the network
                I = imresize(app.Data.img,inputSize(1:2));
                [label, scores] = classify(net, I);
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                app.UIAxes2.Title.String = string(label)+", "+num2str(100*scores(classNames==label),3)+"%";
            else
                warndlg('The image must be a gray image.','Warning')
                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ConversionMenu = uimenu(app.UIFigure);
            app.ConversionMenu.Text = 'Conversion';
            app.rgb2grayMenu = uimenu(app.ConversionMenu);
            app.rgb2grayMenu.MenuSelectedFcn = createCallbackFcn(app, @rgb2grayMenuSelected, true);
            app.rgb2grayMenu.Text = 'rgb2gray';
            app.resizeMenu = uimenu(app.ConversionMenu);
            app.resizeMenu.MenuSelectedFcn = createCallbackFcn(app, @resizeMenuSelected, true);
            app.resizeMenu.Text = 'resize';
            app.split2rgbMenu = uimenu(app.ConversionMenu);
            app.split2rgbMenu.MenuSelectedFcn = createCallbackFcn(app, @split2rgbMenuSelected, true);
            app.split2rgbMenu.Text = 'split2rgb';
            app.rotateMenu = uimenu(app.ConversionMenu);
            app.rotateMenu.MenuSelectedFcn = createCallbackFcn(app, @rotateMenuSelected, true);
            app.rotateMenu.Text = 'rotate';
            app.FourierMenu = uimenu(app.UIFigure);
            app.FourierMenu.Text = 'Fourier';
            app.LowpassMenu = uimenu(app.FourierMenu);
            app.LowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @LowpassMenuSelected, true);
            app.LowpassMenu.Text = 'Lowpass';
            app.HighpassMenu = uimenu(app.FourierMenu);
            app.HighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighpassMenuSelected, true);
            app.HighpassMenu.Text = 'Highpass';
            app.ButterworthlowpassMenu = uimenu(app.FourierMenu);
            app.ButterworthlowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthlowpassMenuSelected, true);
            app.ButterworthlowpassMenu.Text = 'Butterworth lowpass';
            app.ButterworthhighpassMenu = uimenu(app.FourierMenu);
            app.ButterworthhighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthhighpassMenuSelected, true);
            app.ButterworthhighpassMenu.Text = 'Butterworth highpass';
            app.GaussianlowpassMenu = uimenu(app.FourierMenu);
            app.GaussianlowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianlowpassMenuSelected, true);
            app.GaussianlowpassMenu.Text = 'Gaussian lowpass';
            app.GaussianhighpassMenu = uimenu(app.FourierMenu);
            app.GaussianhighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianhighpassMenuSelected, true);
            app.GaussianhighpassMenu.Text = 'Gaussian highpass';
            app.ThresholdingMenu = uimenu(app.UIFigure);
            app.ThresholdingMenu.Text = 'Thresholding';
            app.OtsuMenu = uimenu(app.ThresholdingMenu);
            app.OtsuMenu.MenuSelectedFcn = createCallbackFcn(app, @OtsuMenuSelected, true);
            app.OtsuMenu.Text = 'Otsu';
            app.UserMenu = uimenu(app.ThresholdingMenu);
            app.UserMenu.MenuSelectedFcn = createCallbackFcn(app, @UserMenuSelected, true);
            app.UserMenu.Text = 'User';
            app.EdgeMenu = uimenu(app.UIFigure);
            app.EdgeMenu.Text = 'Edge';
            app.SobelMenu = uimenu(app.EdgeMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.PrewittMenu = uimenu(app.EdgeMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.RobertsMenu = uimenu(app.EdgeMenu);
            app.RobertsMenu.MenuSelectedFcn = createCallbackFcn(app, @RobertsMenuSelected, true);
            app.RobertsMenu.Text = 'Roberts';
            app.logMenu = uimenu(app.EdgeMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.zerocrossMenu = uimenu(app.EdgeMenu);
            app.zerocrossMenu.MenuSelectedFcn = createCallbackFcn(app, @zerocrossMenuSelected, true);
            app.zerocrossMenu.Text = 'zerocross';
            app.CannyMenu = uimenu(app.EdgeMenu);
            app.CannyMenu.MenuSelectedFcn = createCallbackFcn(app, @CannyMenuSelected, true);
            app.CannyMenu.Text = 'Canny';
            app.approxcannyMenu = uimenu(app.EdgeMenu);
            app.approxcannyMenu.MenuSelectedFcn = createCallbackFcn(app, @approxcannyMenuSelected, true);
            app.approxcannyMenu.Text = 'approxcanny';
            app.HoughMenu = uimenu(app.EdgeMenu);
            app.HoughMenu.MenuSelectedFcn = createCallbackFcn(app, @HoughMenuSelected, true);
            app.HoughMenu.Text = 'Hough';
            app.SnakeMenu = uimenu(app.EdgeMenu);
            app.SnakeMenu.MenuSelectedFcn = createCallbackFcn(app, @SnakeMenuSelected, true);
            app.SnakeMenu.Text = 'Snake';
            app.DeepLearningMenu = uimenu(app.UIFigure);
            app.DeepLearningMenu.Text = 'Deep Learning';
            app.ColorGoogLeNetMenu = uimenu(app.DeepLearningMenu);
            app.ColorGoogLeNetMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorGoogLeNetMenuSelected, true);
            app.ColorGoogLeNetMenu.Text = 'Color GoogLeNet';
            app.GrayScaleGooLeNetMenu = uimenu(app.DeepLearningMenu);
            app.GrayScaleGooLeNetMenu.MenuSelectedFcn = createCallbackFcn(app, @GrayScaleGooLeNetMenuSelected, true);
            app.GrayScaleGooLeNetMenu.Text = 'GrayScale GooLeNet';
            app.ThresholdingSliderLabel = uilabel(app.UIFigure);
            app.ThresholdingSliderLabel.Visible = 'off';
            app.ThresholdingSliderLabel.Text = 'Thresholding';
            app.ThresholdingSlider = uislider(app.UIFigure);
            app.ThresholdingSlider.Limits = [0 255];
            app.ThresholdingSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdingSliderValueChanged, true);
            app.ThresholdingSlider.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end