classdef ipapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        WatermarkingMenu        matlab.ui.container.Menu
        BitplaneMenu            matlab.ui.container.Menu
        LSBMenu                 matlab.ui.container.Menu
        BitplaneRGBorGrayMenu   matlab.ui.container.Menu
        PointProcessingMenu     matlab.ui.container.Menu
        ComplenmentMenu         matlab.ui.container.Menu
        HistogramMenu           matlab.ui.container.Menu
        StretchingMenu          matlab.ui.container.Menu
        EqualizationMenu        matlab.ui.container.Menu
        HistogramRGBMenu        matlab.ui.container.Menu
        EqualizationRGBMenu     matlab.ui.container.Menu
        StretchingRGBMenu       matlab.ui.container.Menu
        FilterMenu              matlab.ui.container.Menu
        AverageMenu             matlab.ui.container.Menu
        GaussianMenu            matlab.ui.container.Menu
        LaplacianMenu           matlab.ui.container.Menu
        LoGMenu                 matlab.ui.container.Menu
        UnsharpMenu             matlab.ui.container.Menu
        CustomizedMenu          matlab.ui.container.Menu
        CustomizedGaussianMenu  matlab.ui.container.Menu
        ROIMenu                 matlab.ui.container.Menu
        PointMenu               matlab.ui.container.Menu
        LineMenu                matlab.ui.container.Menu
        RectangleMenu           matlab.ui.container.Menu
        PolygonMenu             matlab.ui.container.Menu
        EllipseMenu             matlab.ui.container.Menu
        FreehandMenu            matlab.ui.container.Menu
        ROIaverageMenu          matlab.ui.container.Menu
        MosaicMenu              matlab.ui.container.Menu
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [app.Data.FileName, app.Data.PathName] = uigetfile( ...
                {  '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
                'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';...
                '*.bmp','WindowsBitmap (*.bmp)';...
                '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
                '*.pbm','PortableBitmap (*.pbm)';...
                '*.pgm','PortableGraymap(*.pgm)';...
                '*.png','PortableNetwork Graphics (*.png)';...
                '*.ppm','PortablePixmap(*.ppm)';...
                '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
                '*.*', 'All Files (*.*)'},...
                'Select an Image File to Open');
            figure(app.UIFigure);
            
            if(app.Data.FileName)==0
                return
            else
                %read in image
                app.Data.img= imread([app.Data.PathName, app.Data.FileName]);
                app.Data.result= app.Data.img;
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes);
                %get image information
                [app.Data.rows,  app.Data.cols,  app.Data.dim] = size(app.Data.img);
                info = imfinfo([app.Data.PathName,  app.Data.FileName]);
                app.Data.bitdepth= info.BitDepth;
                app.Data.ColorType= info.ColorType;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile( ...
                {  '*.bmp','WindowsBitmap (*.bmp)'; ...
                '*.jpg','JPEG(*.jpg)'; ...
                '*.pbm','PortableBitmap (*.pbm)'; ...
                '*.pgm','PortableGraymap(*.pgm)'; ...
                '*.png','PortableNetwork Graphics (*.png)'; ...
                '*.ppm','PortablePixmap(*.ppm)'; ...
                '*.tif','TaggedImage File Format (*.tif)'}, ...
                'Save Image as',...
                'untitled');
            figure(app.UIFigure);
            if(filename)==0
                return
            else
                fmt(1,:)='bmp';
                fmt(2,:)='jpg';
                fmt(3,:)='pbm';
                fmt(4,:)='pgm';
                fmt(5,:)='png';
                fmt(6,:)='ppm';
                fmt(7,:)='tif';
                %write out image
                imwrite(app.Data.result,  [pathname, filename], fmt(filterindex,:));
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
            
        end

        % Menu selected function: BitplaneMenu
        function BitplaneMenuSelected(app, event)
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %extract bitplaneimage
                imgbp= zeros(size(app.Data.img));
                for i=1:8
                    imgbp(:,:,i) = bitget(app.Data.img,i);
                end
                %show image
                SI = figure;
                figure(SI);
                for i=1:8
                    subplot(2,4,i);
                    imshow(imgbp(:,:,i), []);
                    title(['Bitplane', num2str(i)]);
                end
            else
                warndlg('The image is not an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: LSBMenu
        function LSBMenuSelected(app, event)
            [FileName, PathName] = uigetfile( ...
                {  '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
                'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';
                '*.bmp','WindowsBitmap (*.bmp)';...
                '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
                '*.pbm','PortableBitmap (*.pbm)';...
                '*.pgm','PortableGraymap(*.pgm)';...
                '*.png','PortableNetwork Graphics (*.png)';...
                '*.ppm','PortablePixmap(*.ppm)';...
                '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
                '*.*', 'All Files (*.*)'},...
                'Select a Watermark Image to Open');
            if(FileName)==0
                return
            else
                if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                    %read in watermark image
                    water_img= imread([PathName,FileName]);
                    water_info= imfinfo([PathName,FileName]);
                    [rows, cols, dim] = size(water_img);
                    if(water_info.BitDepth)==1 %watermark is binary
                        app.Data.result(1:rows,1:cols)=bitset(app.Data.img(1:rows,1:cols),4,water_img);
                        %show image
                        imshow(app.Data.result, 'Parent', app.UIAxes2);
                        
                        %compute and show PSNR
                        mse= sum(sum((double(app.Data.img)-double(app.Data.result))^2))/(app.Data.rows*app.Data.cols);
                        PSNR = 10*log10(255*255/mse)
                    else
                        warndlg('The watermark image is not binary.','Warning');
                        return;
                    end
                else
                    warndlg('The source image is not an 8-bit grayscale image.','Warning');
                    return;
                end
            end
        end

        % Menu selected function: ComplenmentMenu
        function ComplenmentMenuSelected(app, event)
            %image complement
            app.Data.result= imcomplement(app.Data.img);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            %image histogram
            
            SI = figure;
            figure(SI);
            imhist(app.Data.img);
            
        end

        % Menu selected function: StretchingMenu
        function StretchingMenuSelected(app, event)
            %image stretching
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                app.Data.result= imadjust(app.Data.img);
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: EqualizationMenu
        function EqualizationMenuSelected(app, event)
            %image stretching
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                app.Data.result= histeq(app.Data.img);
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: AverageMenu
        function AverageMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('average', 7);
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
            
        end

        % Menu selected function: GaussianMenu
        function GaussianMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('gaussian',8)
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: LaplacianMenu
        function LaplacianMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('Laplacian')
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: LoGMenu
        function LoGMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('log')
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: UnsharpMenu
        function UnsharpMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('unsharp')
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: CustomizedMenu
        function CustomizedMenuSelected(app, event)
            %Create and open input dialog box
            prompt={'Rows (Mask Height):', 'Columns (Mask Width):'};
            name='Customized Filter';
            numlines=1;
            defaultanswer={'7', '7'};
            
            answer = inputdlg(prompt, name, numlines, defaultanswer);
            
            if isempty(answer) || isempty(answer{1}) || isempty(answer{2})
                warndlg('Error inputs.','Warning')
                return
            else
                %Create predefined 2-D filter
                h = fspecial('average', [str2double(answer{1}) str2double(answer{2})]);
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                
            end
        end

        % Menu selected function: PointMenu
        function PointMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw point
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawpoint(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: LineMenu
        function LineMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw line
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawline(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: RectangleMenu
        function RectangleMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw rectangle
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawrectangle(app.UIAxes, 'Color', 'r');
            roi.Position= round(roi.Position);
            roi.Position
            
            app.Data.result= app.Data.img(roi.Position(2)+1:roi.Position(2)+roi.Position(4), ...
                roi.Position(1)+1:roi.Position(1)+roi.Position(3),:);
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
            
        end

        % Menu selected function: PolygonMenu
        function PolygonMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw polygon
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawpolygon(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %create binary mask image from roi
            mask = createMask(roi);
            
            app.Data.result= uint8(mask.*double(app.Data.img));
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: EllipseMenu
        function EllipseMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw ellipse
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawellipse(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %create binary mask image from roi
            mask = createMask(roi);
            
            app.Data.result= uint8(mask.*double(app.Data.img));
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: FreehandMenu
        function FreehandMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw freehand
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawfreehand(app.UIAxes, 'Color', 'r');
            
            %create binary mask image from roi
            mask = createMask(roi);
            
            app.Data.result= uint8(mask.*double(app.Data.img));
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: ROIaverageMenu
        function ROIaverageMenuSelected(app, event)
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %set ROI function disable
                set(app.ROIMenu,'Enable','off');
                
                %draw rectangle
                imshow(app.Data.img, 'Parent', app.UIAxes);
                roi= drawrectangle(app.UIAxes, 'Color', 'r');
                roi.Position
                
                %create binary mask image from roi
                mask = createMask(roi);
                
                %Create predefined 2-D filter
                f = fspecial('average',[25,25]);
                
                %image ROI filter
                app.Data.result= roifilt2(f, app.Data.img, mask);
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                
                %set ROI function enable
                set(app.ROIMenu,'Enable','on');
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: MosaicMenu
        function MosaicMenuSelected(app, event)
            %set ROI function disable
            
            set(app.ROIMenu,'Enable','off');
            
            %draw rectangle
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawrectangle(app.UIAxes, 'Color', 'r');
            roi.Position= round(roi.Position);
            roi.Position
            
            
            roimg= app.Data.img(roi.Position(2)+1:roi.Position(2)+roi.Position(4), ...
                roi.Position(1)+1:roi.Position(1)+roi.Position(3),:);
            
            
            questions = {'pix_grp size'};
            
            %inputdlg!!
            size = inputdlg(questions)
            
            %to str
            pix_grp = str2num(size{1})
            
            roimg= imresize(roimg,[floor(roi.Position(4)/pix_grp) floor(roi.Position(3)/pix_grp)]);
            roimg= imresize(roimg,[roi.Position(4), roi.Position(3)],'nearest');
            
            app.Data.result= app.Data.img;
            app.Data.result(roi.Position(2)+1:roi.Position(2)+roi.Position(4), roi.Position(1)+1:roi.Position(1)+roi.Position(3),:) = roimg;
            
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
            
        end

        % Menu selected function: CustomizedGaussianMenu
        function CustomizedGaussianMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                
                promot_fspecial = {'hsize:','sigma:'};
                name='Customized Gaussian';
                numlines = 1;
                defaultanswer={'8', '0.7'};
                
                answer = inputdlg(promot_fspecial,name,numlines,defaultanswer);
                
                if isempty(answer) || isempty(answer{1}) || isempty(answer{2})
                    warndlg('Error inputs.','Warning')
                    return
                else
                    h = fspecial('gaussian',str2num(answer{1}),str2num(answer{2}))
                    %image 2-D digital filter
                    app.Data.result= imfilter(app.Data.img, h);
                    %show image
                    imshow(app.Data.result, 'Parent', app.UIAxes2);
                end
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: HistogramRGBMenu
        function HistogramRGBMenuSelected(app, event)
            %             [FileName, PathName] = uigetfile( ...
            %                 {  '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
            %                 'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';
            %                 '*.bmp','WindowsBitmap (*.bmp)';...
            %                 '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
            %                 '*.pbm','PortableBitmap (*.pbm)';...
            %                 '*.pgm','PortableGraymap(*.pgm)';...
            %                 '*.png','PortableNetwork Graphics (*.png)';...
            %                 '*.ppm','PortablePixmap(*.ppm)';...
            %                 '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
            %                 '*.*', 'All Files (*.*)'},...
            %                 'Select a Watermark Image to Open');
            
            
            %             I=imread([PathName,FileName]);
            I = app.Data.img;
            
            h=figure();
            set(h,'position',[300 100 1000 600]);
            
            Ir=I(:,:,1);%提取红色
            
            Ig=I(:,:,2);%提取綠色
            
            Ib=I(:,:,3);%提取藍色
            
            
            subplot(2,4,1);imshow(I);
            title('A). 原始的圖像');


            subplot(2,4,2);
            imhist(Ir);
            title('I). 红色的直方圖');


            subplot(2,4,3);
            imhist(Ig);
            title('I). 綠色的直方圖');


            subplot(2,4,4);
            imhist(Ib);
            title('I). 藍色的直方圖');
            
            
        end

        % Menu selected function: EqualizationRGBMenu
        function EqualizationRGBMenuSelected(app, event)
            
            I = app.Data.img;
            
            YUV = rgb2ntsc(I);
            
            Heq = histeq(YUV);
            
            
            %             YUV_mod = YUV;
            %             YUV_mod(:,:,3) = Heq;
            
            RGB = ntsc2rgb(Heq);
            
            figure,subplot(1,2,1),imshow(I);title('Before Histogram Equalization');
            
            subplot(1,2,2),imshow(RGB);title('After Histogram Equalization');
        end

        % Menu selected function: BitplaneRGBorGrayMenu
        function BitplaneRGBorGrayMenuSelected(app, event)
            
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %extract bitplaneimage
                imgbp= zeros(size(app.Data.img));
                for i=1:8
                    imgbp(:,:,i) = bitget(app.Data.img,i);
                end
                %show image
                SI = figure;
                figure(SI);
                for i=1:8
                    subplot(2,4,i);
                    imshow(imgbp(:,:,i), []);
                    title(['Bitplane', num2str(i)]);
                end
            else
                rgbImage = app.Data.img
                
                % 提取單獨的紅色、綠色和藍色通道。
                redChannel = rgbImage(:, :, 1);
                greenChannel = rgbImage(:, :, 2);
                blueChannel = rgbImage(:, :, 3);
                
                %imgbp= zeros(size(app.Data.img));
                %[rows, cols, dim] = size(greenChannel);
                SI = figure;
                figure(SI);
                for i=0:7
                    bitPlaneImage = bitget(redChannel,i+1);
                    subplot(6,4,i+1);
                    imshow(bitPlaneImage, []);
                    title(['Bitplane red', num2str(i+1)]);
                    
                    bitPlaneImage1 = bitget(greenChannel,i+1);
                    subplot(6,4,i+9);
                    imshow(bitPlaneImage1, []);
                    title(['Bitplane green', num2str(i+1)]);
                    
                    bitPlaneImage2 = bitget(blueChannel,i+1);
                    subplot(6,4,i+17);
                    imshow(bitPlaneImage2, []);
                    title(['Bitplane blue', num2str(i+1)]);
                    
                    %imgbp(:,:,i) = bitget(app.Data.img,i);
                end
            end
        end

        % Menu selected function: StretchingRGBMenu
        function StretchingRGBMenuSelected(app, event)
            I = app.Data.img
            
            YUV = rgb2ntsc(I);
            %[.2 .3 0; .6 .7 1] imagjust的函數
            HeqRGB = imadjust(YUV,[.2 .3 0; .6 .7 1],[])
            
            RGB = ntsc2rgb(HeqRGB);
            
            
            figure,subplot(1,2,1),imshow(I);title('Before Histogram Stretching');
            
            subplot(1,2,2),imshow(RGB);title('After Histogram Stretching');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.WatermarkingMenu = uimenu(app.UIFigure);
            app.WatermarkingMenu.Text = 'Watermarking';
            app.BitplaneMenu = uimenu(app.WatermarkingMenu);
            app.BitplaneMenu.MenuSelectedFcn = createCallbackFcn(app, @BitplaneMenuSelected, true);
            app.BitplaneMenu.Text = 'Bitplane';
            app.LSBMenu = uimenu(app.WatermarkingMenu);
            app.LSBMenu.MenuSelectedFcn = createCallbackFcn(app, @LSBMenuSelected, true);
            app.LSBMenu.Text = 'LSB';
            app.BitplaneRGBorGrayMenu = uimenu(app.WatermarkingMenu);
            app.BitplaneRGBorGrayMenu.MenuSelectedFcn = createCallbackFcn(app, @BitplaneRGBorGrayMenuSelected, true);
            app.BitplaneRGBorGrayMenu.Text = 'Bitplane RGB or Gray';
            app.PointProcessingMenu = uimenu(app.UIFigure);
            app.PointProcessingMenu.Text = 'Point Processing';
            app.ComplenmentMenu = uimenu(app.PointProcessingMenu);
            app.ComplenmentMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplenmentMenuSelected, true);
            app.ComplenmentMenu.Text = 'Complenment';
            app.HistogramMenu = uimenu(app.PointProcessingMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.StretchingMenu = uimenu(app.PointProcessingMenu);
            app.StretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @StretchingMenuSelected, true);
            app.StretchingMenu.Text = 'Stretching';
            app.EqualizationMenu = uimenu(app.PointProcessingMenu);
            app.EqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @EqualizationMenuSelected, true);
            app.EqualizationMenu.Text = 'Equalization';
            app.HistogramRGBMenu = uimenu(app.PointProcessingMenu);
            app.HistogramRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramRGBMenuSelected, true);
            app.HistogramRGBMenu.Text = 'Histogram RGB';
            app.EqualizationRGBMenu = uimenu(app.PointProcessingMenu);
            app.EqualizationRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @EqualizationRGBMenuSelected, true);
            app.EqualizationRGBMenu.Text = 'Equalization RGB';
            app.StretchingRGBMenu = uimenu(app.PointProcessingMenu);
            app.StretchingRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @StretchingRGBMenuSelected, true);
            app.StretchingRGBMenu.Text = 'Stretching RGB';
            app.FilterMenu = uimenu(app.UIFigure);
            app.FilterMenu.Text = 'Filter';
            app.AverageMenu = uimenu(app.FilterMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageMenuSelected, true);
            app.AverageMenu.Text = 'Average';
            app.GaussianMenu = uimenu(app.FilterMenu);
            app.GaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussianMenu.Text = 'Gaussian';
            app.LaplacianMenu = uimenu(app.FilterMenu);
            app.LaplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @LaplacianMenuSelected, true);
            app.LaplacianMenu.Text = 'Laplacian';
            app.LoGMenu = uimenu(app.FilterMenu);
            app.LoGMenu.MenuSelectedFcn = createCallbackFcn(app, @LoGMenuSelected, true);
            app.LoGMenu.Text = 'LoG';
            app.UnsharpMenu = uimenu(app.FilterMenu);
            app.UnsharpMenu.MenuSelectedFcn = createCallbackFcn(app, @UnsharpMenuSelected, true);
            app.UnsharpMenu.Text = 'Unsharp';
            app.CustomizedMenu = uimenu(app.FilterMenu);
            app.CustomizedMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomizedMenuSelected, true);
            app.CustomizedMenu.Text = 'Customized';
            app.CustomizedGaussianMenu = uimenu(app.FilterMenu);
            app.CustomizedGaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomizedGaussianMenuSelected, true);
            app.CustomizedGaussianMenu.Text = 'Customized Gaussian';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.PointMenu = uimenu(app.ROIMenu);
            app.PointMenu.MenuSelectedFcn = createCallbackFcn(app, @PointMenuSelected, true);
            app.PointMenu.Text = 'Point';
            app.LineMenu = uimenu(app.ROIMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @LineMenuSelected, true);
            app.LineMenu.Text = 'Line';
            app.RectangleMenu = uimenu(app.ROIMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @RectangleMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.PolygonMenu = uimenu(app.ROIMenu);
            app.PolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @PolygonMenuSelected, true);
            app.PolygonMenu.Text = 'Polygon';
            app.EllipseMenu = uimenu(app.ROIMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @EllipseMenuSelected, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.FreehandMenu = uimenu(app.ROIMenu);
            app.FreehandMenu.MenuSelectedFcn = createCallbackFcn(app, @FreehandMenuSelected, true);
            app.FreehandMenu.Text = 'Freehand';
            app.ROIaverageMenu = uimenu(app.ROIMenu);
            app.ROIaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIaverageMenuSelected, true);
            app.ROIaverageMenu.Text = 'ROIaverage';
            app.MosaicMenu = uimenu(app.ROIMenu);
            app.MosaicMenu.MenuSelectedFcn = createCallbackFcn(app, @MosaicMenuSelected, true);
            app.MosaicMenu.Text = 'Mosaic';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end